classdef APP_Analisis_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        NTButton          matlab.ui.control.Button
        PTButton          matlab.ui.control.Button
        SSTButton         matlab.ui.control.Button
        HidrogramaButton  matlab.ui.control.Button
        Grafica           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: Grafica
        function UIAxesButtonDown(app, event)

        end

        % Button pushed function: HidrogramaButton
        function HidrogramaButtonPushed(app, event)
            cla(app.Grafica,'reset')
            fech=evalin('base', "Fechas");
            datos=evalin('base', "Datos");
            plot(app.Grafica,fech, datos(:,2));
            ylabel(app.Grafica,'Caudal(L/s)');
            xlabel(app.Grafica,'Fecha');
            subtitle(app.Grafica,'Hidrograma', 'FontWeight','bold');
        end

        % Button pushed function: SSTButton
        function SSTButtonPushed(app, event)
            cla(app.Grafica, "reset");
            fech=evalin('base', "Fechas");
            datos=evalin('base', "Datos");
            plot(app.Grafica,fech, datos(:,3));
            ylabel(app.Grafica,'Concentración(mg/L)');
            xlabel(app.Grafica,'Fecha');
            subtitle(app.Grafica,'SST', 'FontWeight','bold');
        end

        % Button pushed function: PTButton
        function PTButtonPushed(app, event)
            cla(app.Grafica, "reset");
            fech=evalin('base', "Fechas");
            datos=evalin('base', "Datos");
            plot(app.Grafica,fech, datos(:,4));
            ylabel(app.Grafica,'Concentración(mg/L)');
            xlabel(app.Grafica,'Fecha');
            subtitle(app.Grafica,'PT', 'FontWeight','bold');
        end

        % Button pushed function: NTButton
        function NTButtonPushed(app, event)
            cla(app.Grafica, "reset");
            fech=evalin('base', "Fechas");
            datos=evalin('base', "Datos");
            plot(app.Grafica,fech, datos(:,5));
            ylabel(app.Grafica,'Concentración(mg/L)');
            xlabel(app.Grafica,'Fecha');
            subtitle(app.Grafica,'NT', 'FontWeight','bold');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Grafica = uiaxes(app.UIFigure);
            title(app.Grafica, 'Title')
            xlabel(app.Grafica, 'X')
            ylabel(app.Grafica, 'Y')
            zlabel(app.Grafica, 'Z')
            app.Grafica.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.HidrogramaButton = uibutton(app.UIFigure, 'push');
            app.HidrogramaButton.ButtonPushedFcn = createCallbackFcn(app, @HidrogramaButtonPushed, true);
            app.HidrogramaButton.Text = 'Hidrograma';
            app.SSTButton = uibutton(app.UIFigure, 'push');
            app.SSTButton.ButtonPushedFcn = createCallbackFcn(app, @SSTButtonPushed, true);
            app.SSTButton.Text = 'SST';
            app.PTButton = uibutton(app.UIFigure, 'push');
            app.PTButton.ButtonPushedFcn = createCallbackFcn(app, @PTButtonPushed, true);
            app.PTButton.Text = 'PT';
            app.NTButton = uibutton(app.UIFigure, 'push');
            app.NTButton.ButtonPushedFcn = createCallbackFcn(app, @NTButtonPushed, true);
            app.NTButton.Text = 'NT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_Analisis_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end