classdef APP_Generacion_Ejecucion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Generacion                     matlab.ui.Figure
        Predef                         matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        LugaryFechaTab                 matlab.ui.container.Tab
        ActualizarFechasLimitesButton  matlab.ui.control.Button
        FechaFinDatePickerLabel        matlab.ui.control.Label
        FechaFin                       matlab.ui.control.DatePicker
        FechaInicioLabel               matlab.ui.control.Label
        FechaInicio                    matlab.ui.control.DatePicker
        CiudadDropDownLabel            matlab.ui.control.Label
        Ciudad                         matlab.ui.control.DropDown
        SubcuencaTab                   matlab.ui.container.Tab
        Imper                          matlab.ui.control.NumericEditField
        ImpermeabilidadLabel           matlab.ui.control.Label
        TipodeSueloDropDown            matlab.ui.control.DropDown
        TipodeSueloDropDownLabel       matlab.ui.control.Label
        PLong                          matlab.ui.control.NumericEditField
        PendienteLongLabel             matlab.ui.control.Label
        PL                             matlab.ui.control.NumericEditField
        PendienteLateralLabel          matlab.ui.control.Label
        reahaLabel                     matlab.ui.control.Label
        Area                           matlab.ui.control.NumericEditField
        UsosdeSueloTab                 matlab.ui.container.Tab
        UrbanoNum                      matlab.ui.control.NumericEditField
        UsoUrbano                      matlab.ui.control.CheckBox
        VerdeNum                       matlab.ui.control.NumericEditField
        UsoVerde                       matlab.ui.control.CheckBox
        GeneralNum                     matlab.ui.control.NumericEditField
        UsoGeneral                     matlab.ui.control.CheckBox
        TipodeAlcantarilladoTab        matlab.ui.container.Tab
        Alcantarillado                 matlab.ui.container.ButtonGroup
        UnitarioB                      matlab.ui.control.RadioButton
        SeparadoB                      matlab.ui.control.RadioButton
        Estado_3                       matlab.ui.control.Label
        b3                             matlab.ui.control.Button
        Titulo                         matlab.ui.control.Label
        Subtitulo1                     matlab.ui.control.Label
        b4                             matlab.ui.control.Button
        Estado_2                       matlab.ui.control.Label
        b2                             matlab.ui.control.Button
        Subtitulo2                     matlab.ui.control.Label
        b1                             matlab.ui.control.Button
        Estado                         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Area
        function AreaValueChanged(app, event)
            area = app.Area.Value;
            assignin('base', "APP_Area", area);
        end

        % Button pushed function: b1
        function b1ButtonPushed(app, event)
            run('MC_Generacion.m');
            app.Estado.BackgroundColor = "g";
        end

        % Value changed function: FechaInicio
        function FechaInicioValueChanged(app, event)
            app.FechaInicio.Limits = [datetime([2009 5 10]) datetime([2021 8 5])];
            fecha_In = app.FechaInicio.Value;
            assignin('base', "APP_date_time_in", fecha_In);
        end

        % Value changed function: FechaFin
        function FechaFinValueChanged(app, event)
            fecha_Fin = app.FechaFin.Value;
            assignin('base', "APP_date_time_fin", fecha_Fin);
        end

        % Callback function
        function CiudadValueChanged(app, event)
            ciudad = app.Ciudad.Value;
            assignin('base', "APP_ciudad", ciudad);
        end

        % Callback function
        function DropDownValueChanged(app, event)
            valueC = app.Ciudad.Value;
            assignin('base', "APP_ciudad_Prueba", valueC);
        end

        % Value changed function: Ciudad
        function CiudadValueChanged2(app, event)
            ciudad = app.Ciudad.Value;
            assignin('base', "APP_ciudad", ciudad);
        end

        % Button pushed function: b2
        function b2ButtonPushed(app, event)
            run ("MC_Ejecucion.m");
            app.Estado_2.BackgroundColor = "g";
        end

        % Button pushed function: b4
        function b4ButtonPushed(app, event)
           APP_Analisis_2;
        end

        % Button pushed function: b3
        function b3ButtonPushed(app, event)
            run("MC_Analisis.m");
            app.Estado_3.BackgroundColor = "g";
        end

        % Callback function
        function InicioValueChanged(app, event)
            app.Inicio.Limits = [datetime([2009 5 10]) datetime([2021 8 5])];
            fecha_In = app.FechaInicio.Value;
            assignin('base', "APP_date_time_in", fecha_In);

        end

        % Value changed function: PL
        function PLValueChanged(app, event)
            value = app.PL.Value;
            assignin('base', "APP_PendLat", value);
        end

        % Value changed function: PLong
        function PLongValueChanged(app, event)
            value = app.PLong.Value;
            assignin('base', "APP_PendLong", value);
        end

        % Value changed function: TipodeSueloDropDown
        function TipodeSueloDropDownValueChanged(app, event)
            value = app.TipodeSueloDropDown.Value;
            assignin('base', "APP_suelo", value);
        end

        % Value changed function: Imper
        function ImperValueChanged(app, event)
            value = app.Imper.Value;
            assignin('base', "APP_Imper", value);
        end

        % Value changed function: UsoGeneral
        function UsoGeneralValueChanged(app, event)
            value_Gen = app.UsoGeneral.Value;
            assignin('base', "APP_Uso_General", value_Gen);
        end

        % Value changed function: GeneralNum
        function GeneralNumValueChanged(app, event)
            value_num_gen = app.GeneralNum.Value;
            assignin('base', "APP_Num_General", value_num_gen);
        end

        % Value changed function: VerdeNum
        function VerdeNumValueChanged(app, event)
            value_num_verde = app.VerdeNum.Value;
            assignin('base', "APP_Num_Verde", value_num_verde);
        end

        % Value changed function: UrbanoNum
        function UrbanoNumValueChanged(app, event)
            value_num_urb = app.UrbanoNum.Value;
            assignin('base', "APP_Num_Urbano", value_num_urb);
        end

        % Value changed function: UsoVerde
        function UsoVerdeValueChanged(app, event)
            value_ver = app.UsoVerde.Value;
            assignin('base', "APP_Uso_Verde", value_ver);
        end

        % Value changed function: UsoUrbano
        function UsoUrbanoValueChanged(app, event)
            value_urb = app.UsoUrbano.Value;
            assignin('base', "APP_Uso_Urbano", value_urb);
        end

        % Selection changed function: Alcantarillado
        function AlcantarilladoSelectionChanged(app, event)
            selectedButton = app.Alcantarillado.SelectedObject;
            if selectedButton==app.UnitarioB
                q_seco=1;
            else
                q_seco=0;
            end
            assignin('base', "APP_Inc_Q_Seco", q_seco);
        end

        % Button pushed function: Predef
        function PredefButtonPushed(app, event)
            %Ciudad
            pred_ciudad='Santander';
            assignin('base', "APP_ciudad", pred_ciudad);
            app.Ciudad.Value=pred_ciudad;
           
            %Fecha Inicio
            pred_fecha_in=datetime([2018 1 1]);
            assignin('base', "APP_date_time_in", pred_fecha_in);
            app.FechaInicio.Value=pred_fecha_in;

            %Fecha Fin
            pred_fecha_fin=datetime([2019 1 1]);
            assignin('base', "APP_date_time_fin", pred_fecha_fin);
            app.FechaFin.Value=pred_fecha_fin;
                
            %Area
            pred_Area=100;
            assignin('base', "APP_Area", pred_Area);
            app.Area.Value=pred_Area;

            %Pendiente Lateral
            pred_PendL=1;
            assignin('base', "APP_PendLat", pred_PendL);
            app.PL.Value=pred_PendL;

            %Pendiente Longitudinal
            pred_PendLon=1;
            assignin('base', "APP_PendLong", pred_PendLon);
            app.PLong.Value=pred_PendLon;

            %Tipo de Suelo
            pred_Suelo='Tipo B';
            assignin('base', "APP_suelo", pred_Suelo);
            app.TipodeSueloDropDown.Value=pred_Suelo;

            %Impermeabilidad
            pred_Imper=80;
            assignin('base', "APP_Imper", pred_Imper);
            app.Imper.Value=pred_Imper;

            %Uso de Suelo General
            pred_Gen=1;
            assignin('base', "APP_Uso_General", pred_Gen);
            app.UsoUrbano.Value=pred_Gen;

            pred_por_Gen=100;
            assignin('base', "APP_Num_General", pred_por_Gen);
            app.GeneralNum.Value=pred_por_Gen;

            %Uso de Suelo Verde
            pred_Ver=0;
            assignin('base', "APP_Uso_Verde", pred_Ver);
            app.UsoVerde.Value=pred_Ver;

            pred_por_Ver=0;
            assignin('base', "APP_Num_Verde", pred_por_Ver);
            app.VerdeNum.Value=pred_por_Ver;

            %Uso de Suelo Urbano
            pred_Urb=0;
            assignin('base', "APP_Uso_Urbano", pred_Urb);
            app.UsoUrbano.Value=pred_Urb;

            pred_por_Urb=0;
            assignin('base', "APP_Num_Urbano", pred_por_Urb);
            app.UsoUrbano.Value=pred_por_Urb;

            %Tipo de Alcantarillado
            pred_Tip_Alc=0;
            assignin('base', "APP_Inc_Q_Seco", pred_Tip_Alc);
            app.UnitarioB.Value=pred_Tip_Alc;
        end

        % Button pushed function: ActualizarFechasLimitesButton
        function ActualizarFechasLimitesButtonPushed(app, event)
            ciudad_cell={app.Ciudad.Value};
            lim_fechas=readcell('Limites_Fechas.xlsx');
            ver_ciudad = strcmpi(ciudad_cell,lim_fechas(:,1));

            for i=1:length(ver_ciudad)
                if ver_ciudad(i)==1
                    ind=i;
                else
                end
            end
            yi=cell2mat(lim_fechas(ind,2));
            mi=cell2mat(lim_fechas(ind,3));
            di=cell2mat(lim_fechas(ind,4));
            yf=cell2mat(lim_fechas(ind,5));
            mf=cell2mat(lim_fechas(ind,6));
            df=cell2mat(lim_fechas(ind,7));

            app.FechaInicio.Limits = [datetime([yi mi di]) datetime([yf mf df])];
            app.FechaFin.Limits = [datetime([yi mi di]) datetime([yf mf df])];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Generacion = uifigure('Visible', 'off');
            app.Generacion.Name = 'MATLAB App';
            app.Estado = uilabel(app.Generacion);
            app.Estado.Text = 'Estado';
            app.b1 = uibutton(app.Generacion, 'push');
            app.b1.ButtonPushedFcn = createCallbackFcn(app, @b1ButtonPushed, true);
            app.b1.Text = 'Generar Modelo';
            app.Subtitulo2 = uilabel(app.Generacion);
            app.Subtitulo2.Text = 'Generación, Ejecución y Análisis del Modelo';
            app.b2 = uibutton(app.Generacion, 'push');
            app.b2.ButtonPushedFcn = createCallbackFcn(app, @b2ButtonPushed, true);
            app.b2.Text = 'Ejecutar Modelo';
            app.Estado_2 = uilabel(app.Generacion);
            app.Estado_2.Text = 'Estado';
            app.b4 = uibutton(app.Generacion, 'push');
            app.b4.ButtonPushedFcn = createCallbackFcn(app, @b4ButtonPushed, true);
            app.b4.Text = 'Continuar ';
            app.Subtitulo1 = uilabel(app.Generacion);
            app.Subtitulo1.Text = 'Definición de Variables del Modelo';
            app.Titulo = uilabel(app.Generacion);
            app.Titulo.Text = 'Generación, Ejecución y Análisis de Modelos SWMM';
            app.b3 = uibutton(app.Generacion, 'push');
            app.b3.ButtonPushedFcn = createCallbackFcn(app, @b3ButtonPushed, true);
            app.b3.Text = 'Correr Análisis ';
            app.Estado_3 = uilabel(app.Generacion);
            app.Estado_3.Text = 'Estado';
            app.TabGroup = uitabgroup(app.Generacion);
            app.LugaryFechaTab = uitab(app.TabGroup);
            app.LugaryFechaTab.Title = 'Lugar y Fecha';
            app.Ciudad = uidropdown(app.LugaryFechaTab);
            app.Ciudad.Items = {'Seleccione', 'Badajoz', 'Barcelona', 'Bilbao', 'Coruna', 'GranCanaria', 'Granada', 'Madrid', 'Murcia', 'PalmaMallorca', 'Santander', 'Sevilla', 'Valencia', 'Valladolid', 'Zaragoza', 'Cali', 'Cucuta', 'Inirida', 'Leticia', 'Medellin', 'Mitu', 'Pasto', 'Quibdo', 'Yopal'};
            app.Ciudad.ValueChangedFcn = createCallbackFcn(app, @CiudadValueChanged2, true);
            app.Ciudad.Tooltip = {'Seleccione la ciudad donde se correrá el análisis'};
            app.Ciudad.Value = 'Seleccione';
            app.CiudadDropDownLabel = uilabel(app.LugaryFechaTab);
            app.CiudadDropDownLabel.Text = 'Ciudad';
            app.FechaInicio = uidatepicker(app.LugaryFechaTab);
            app.FechaInicio.Limits = [datetime([2009 5 10]) datetime([2019 5 28])];
            app.FechaInicio.ValueChangedFcn = createCallbackFcn(app, @FechaInicioValueChanged, true);
            app.FechaInicio.Tooltip = {'Fecha Inicio Análisis. Del 05/10/2009 en adelante'};
            app.FechaInicioLabel = uilabel(app.LugaryFechaTab);
            app.FechaInicioLabel.Text = 'Fecha Inicio';
            app.FechaFin = uidatepicker(app.LugaryFechaTab);
            app.FechaFin.Limits = [datetime([2009 5 10]) datetime([2019 5 28])];
            app.FechaFin.ValueChangedFcn = createCallbackFcn(app, @FechaFinValueChanged, true);
            app.FechaFin.Tooltip = {'Fecha Fin Análisis. Hasta el 28/05/2019'};
            app.FechaFinDatePickerLabel = uilabel(app.LugaryFechaTab);
            app.FechaFinDatePickerLabel.Text = 'Fecha Fin';
            app.ActualizarFechasLimitesButton = uibutton(app.LugaryFechaTab, 'push');
            app.ActualizarFechasLimitesButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarFechasLimitesButtonPushed, true);
            app.ActualizarFechasLimitesButton.Text = 'Actualizar Fechas Limites';
            app.SubcuencaTab = uitab(app.TabGroup);
            app.SubcuencaTab.Title = 'Subcuenca';
            app.Area = uieditfield(app.SubcuencaTab, 'numeric');
            app.Area.ValueChangedFcn = createCallbackFcn(app, @AreaValueChanged, true);
            app.Area.Tooltip = {'Seleccione el área de cada subcuenca'};
            app.reahaLabel = uilabel(app.SubcuencaTab);
            app.reahaLabel.Text = 'Área (ha)';
            app.PendienteLateralLabel = uilabel(app.SubcuencaTab);
            app.PendienteLateralLabel.Text = 'Pendiente Lateral (%)';
            app.PL = uieditfield(app.SubcuencaTab, 'numeric');
            app.PL.ValueChangedFcn = createCallbackFcn(app, @PLValueChanged, true);
            app.PendienteLongLabel = uilabel(app.SubcuencaTab);
            app.PendienteLongLabel.Text = 'Pendiente Long. (%)';
            app.PLong = uieditfield(app.SubcuencaTab, 'numeric');
            app.PLong.ValueChangedFcn = createCallbackFcn(app, @PLongValueChanged, true);
            app.TipodeSueloDropDownLabel = uilabel(app.SubcuencaTab);
            app.TipodeSueloDropDownLabel.Text = 'Tipo de Suelo';
            app.TipodeSueloDropDown = uidropdown(app.SubcuencaTab);
            app.TipodeSueloDropDown.Items = {'Seleccione', 'Tipo A', 'Tipo B', 'Tipo C', 'Tipo D'};
            app.TipodeSueloDropDown.ValueChangedFcn = createCallbackFcn(app, @TipodeSueloDropDownValueChanged, true);
            app.TipodeSueloDropDown.Value = 'Seleccione';
            app.ImpermeabilidadLabel = uilabel(app.SubcuencaTab);
            app.ImpermeabilidadLabel.Text = 'Impermeabilidad (%)';
            app.Imper = uieditfield(app.SubcuencaTab, 'numeric');
            app.Imper.ValueChangedFcn = createCallbackFcn(app, @ImperValueChanged, true);
            app.UsosdeSueloTab = uitab(app.TabGroup);
            app.UsosdeSueloTab.Title = 'Usos de Suelo';
            app.UsoGeneral = uicheckbox(app.UsosdeSueloTab);
            app.UsoGeneral.ValueChangedFcn = createCallbackFcn(app, @UsoGeneralValueChanged, true);
            app.UsoGeneral.Text = 'Uso General (%)';
            app.UsoGeneral.Value = true;
            app.GeneralNum = uieditfield(app.UsosdeSueloTab, 'numeric');
            app.GeneralNum.ValueChangedFcn = createCallbackFcn(app, @GeneralNumValueChanged, true);
            app.UsoVerde = uicheckbox(app.UsosdeSueloTab);
            app.UsoVerde.ValueChangedFcn = createCallbackFcn(app, @UsoVerdeValueChanged, true);
            app.UsoVerde.Text = 'Uso Verde (%)';
            app.VerdeNum = uieditfield(app.UsosdeSueloTab, 'numeric');
            app.VerdeNum.ValueChangedFcn = createCallbackFcn(app, @VerdeNumValueChanged, true);
            app.UsoUrbano = uicheckbox(app.UsosdeSueloTab);
            app.UsoUrbano.ValueChangedFcn = createCallbackFcn(app, @UsoUrbanoValueChanged, true);
            app.UsoUrbano.Text = 'Uso Urbano (%)';
            app.UrbanoNum = uieditfield(app.UsosdeSueloTab, 'numeric');
            app.UrbanoNum.ValueChangedFcn = createCallbackFcn(app, @UrbanoNumValueChanged, true);
            app.TipodeAlcantarilladoTab = uitab(app.TabGroup);
            app.TipodeAlcantarilladoTab.Title = 'Tipo de Alcantarillado';
            app.Alcantarillado = uibuttongroup(app.TipodeAlcantarilladoTab);
            app.Alcantarillado.SelectionChangedFcn = createCallbackFcn(app, @AlcantarilladoSelectionChanged, true);
            app.Alcantarillado.Title = 'Tipo de Alcantarillado';
            app.SeparadoB = uiradiobutton(app.Alcantarillado);
            app.SeparadoB.Text = 'Separado';
            app.SeparadoB.Value = true;
            app.UnitarioB = uiradiobutton(app.Alcantarillado);
            app.UnitarioB.Text = 'Unitario';
            app.Predef = uibutton(app.Generacion, 'push');
            app.Predef.ButtonPushedFcn = createCallbackFcn(app, @PredefButtonPushed, true);
            app.Predef.Text = 'Asignar Condiciones Base';
            % Show the figure after all components are created
            app.Generacion.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_Generacion_Ejecucion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Generacion)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Generacion)
        end
    end
end