classdef APP_Analisis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Image             matlab.ui.control.Image
        OpcGraf           matlab.ui.container.ButtonGroup
        HidrogramaButton  matlab.ui.control.RadioButton
        SSTButton         matlab.ui.control.RadioButton
        PTButton          matlab.ui.control.RadioButton
        NTButton          matlab.ui.control.RadioButton
        Titulo            matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function b1ButtonPushed(app, event)
            
        end

        % Callback function
        function b2ButtonPushed(app, event)
          
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            
        end

        % Selection changed function: OpcGraf
        function OpcGrafSelectionChanged(app, event)
            selectedButton = app.OpcGraf.SelectedObject;
            if app.SSTButton.Value == 1
                app.Image.ImageSource = 'Resultados Escenario Base/SST.png';
                
            elseif app.PTButton.Value == 1
                 app.Image.ImageSource = 'Resultados Escenario Base/PT.png';
                 
            elseif app.NTButton.Value == 1
                app.Image.ImageSource = 'Resultados Escenario Base/NT.png';
            else
                app.Image.ImageSource = 'Resultados Escenario Base/Hidrograma.png';
            end
        end

        % Callback function
        function VisualizarCheckBoxValueChanged(app, event)
            value = app.VisualizarCheckBox.Value;
            if value ==1
                app.Image.Visible == 'On';
            else
                app.Image.Visible == 'Off';
            end

        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            app.Image.ImageSource = 'Resultados Escenario Base/Hidrograma.png';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.OpcGraf = uibuttongroup(app.UIFigure);
            app.OpcGraf.SelectionChangedFcn = createCallbackFcn(app, @OpcGrafSelectionChanged, true);
            app.OpcGraf.Title = 'Seleccione la Gráfica a Visualizar';
            app.HidrogramaButton = uiradiobutton(app.OpcGraf);
            app.HidrogramaButton.Text = 'Hidrograma';
            app.HidrogramaButton.Value = true;
            app.SSTButton = uiradiobutton(app.OpcGraf);
            app.SSTButton.Text = 'SST';
            app.PTButton = uiradiobutton(app.OpcGraf);
            app.PTButton.Text = 'PT';
            app.NTButton = uiradiobutton(app.OpcGraf);
            app.NTButton.Text = 'NT';
            app.Titulo = uilabel(app.UIFigure);
            app.Titulo.Text = '3) Visualización de Datos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_Analisis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end